classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SolvingEquationusingNumericalMethodsLabel  matlab.ui.control.Label
        BrowseFileButton               matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        GoButton                       matlab.ui.control.Button
        OutputScreenLabel              matlab.ui.control.Label
        EquationEditFieldLabel         matlab.ui.control.Label
        EquationEditField              matlab.ui.control.EditField
        MaxiterationsEditFieldLabel    matlab.ui.control.Label
        MaxiterationsEditField         matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel          matlab.ui.control.Label
        EpsilonEditField               matlab.ui.control.NumericEditField
        FiledirectoryEditFieldLabel    matlab.ui.control.Label
        FiledirectoryEditField         matlab.ui.control.EditField
        MethodSelectionDropDownLabel   matlab.ui.control.Label
        MethodSelectionDropDown        matlab.ui.control.DropDown
        MethodSelectedPanel            matlab.ui.container.Panel
        XLEditFieldLabel               matlab.ui.control.Label
        XLEditField                    matlab.ui.control.NumericEditField
        XUEditFieldLabel               matlab.ui.control.Label
        XUEditField                    matlab.ui.control.NumericEditField
        IntialGuessEditFieldLabel      matlab.ui.control.Label
        IntialGuessEditField           matlab.ui.control.EditField
        ClearButton                    matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        StatusLabel                    matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        CurrentEquationEditFieldLabel  matlab.ui.control.Label
        CurrentEquationEditField       matlab.ui.control.EditField
        IterationstookEditFieldLabel   matlab.ui.control.Label
        IterationstookEditField        matlab.ui.control.NumericEditField
        ApproximaterootEditFieldLabel  matlab.ui.control.Label
        ApproximaterootEditField       matlab.ui.control.NumericEditField
        PrecsionEditFieldLabel         matlab.ui.control.Label
        PrecsionEditField              matlab.ui.control.NumericEditField
        ExcecutionTimesEditFieldLabel  matlab.ui.control.Label
        ExcecutionTimesEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Equations % Holder for equations
        NoOFEquations % Description
        CurrentEquation = 1;
    end
    
    methods (Access = private)
        
        function result = FormulateProcess(app)
            if app.EquationEditField.Value == ""
                result = [];
            else
                str = app.EquationEditField.Value;
                result = split(str,",");
            end
        end
        
        function [root,precision,excecutionTime,iteration, status] = Bisection(app, equstr, XL, XU, es, iterations)
            app.StatusLabel.FontColor = [0.00,0.00,0.00];
            app.UITable.ColumnName = {'iteration' 'Xl' 'Xu' 'Xr' 'F(Xr)' 'es (%)'};
            tstart = cputime;
            equ = str2sym(equstr);
            fxl = subs(equ,XL);
            fxu = subs(equ,XU);
            XrOld = 0;
            if (fxl * fxu < 0)
                iteration = 1;
                error = 99999999;
                while (iteration <= iterations && es < error)
                    Xrnew = (XL + XU)/2;
                    error = abs((Xrnew - XrOld)/Xrnew);
                    fxr = subs(equ,Xrnew);
                    nr = {iteration XL XU Xrnew double(fxr) error*100};
                    app.UITable.Data = [app.UITable.Data; nr];
                    if (fxr < 0)
                        XL = Xrnew;
                    else
                        XU = Xrnew;
                    end
 
                    XrOld = Xrnew;
                    iteration = iteration + 1;
                end
                root = Xrnew;
                precision = floor(2 - log10(2 * error));
                excecutionTime = cputime - tstart;
                status = true;
            else
                app.StatusLabel.FontColor = [1.00,0.00,0.0];
                app.StatusLabel.Text = 'ERROR Bad Interval: Root is impossible to locate';
                root = NaN;
                precision = NaN;
                excecutionTime = NaN;
                iteration = NaN;
                status = false;
                return
            end
        end


        function [root,precision,excecutionTime,iteration, status] = Secant(app, equstr, XL, XU, es, iterations)
             app.StatusLabel.FontColor = [0.00,0.00,0.00];
             app.UITable.ColumnName = {'iteration' 'X(i-1)' 'F(X(i-1))' 'X(i)' 'F(X(i))' 'X(i+1)' 'es (%)'};
             tstart = cputime;
             equ = str2sym(equstr);
             iteration = 1;
             error = 99999999;
                  while (iteration <= iterations && es < error)
                      fxl = subs(equ,XL);
                      fxu = subs(equ,XU);
                      test = fxu - fxl;
                      if(isinf(test) || isnan(test)) 
                        app.StatusLabel.FontColor = [1.00,0.00,0.0];
                        app.StatusLabel.Text = 'ERROR Divergenence Detected: Root is impossible to locate';
                        root = NaN;
                        precision = NaN;
                        excecutionTime = NaN;
                        iteration = NaN;
                        status = false;
                        return
                      end
                      Xr = XU - ((XU - XL)/(fxu - fxl))*fxu;
                      error = abs((Xr - XU)/Xr);
                      nr = {iteration XL double(fxl) XU double(fxu) double(Xr) double(error)*100};
                      disp(nr);
                      app.UITable.Data = [app.UITable.Data; nr];
                      XL = XU;
                      disp(Xr);
                      XU = double(Xr);
                      iteration = iteration + 1;
                  end
                root = Xr;
                precision = floor(2 - log10(2 * error));
                excecutionTime = cputime - tstart;
                status = true;
                return
        end
        
        
        function [root,precision,excecutionTime,iteration, status] = FixedPoint(app, equstr, X0, es, iterations)
                app.StatusLabel.FontColor = [0.00,0.00,0.00];
                app.UITable.ColumnName = {'iteration' 'Xr' 'es (%)'};
                tstart = cputime;
                g = str2sym(equstr);
                gdash = diff(g);
                gdash_value = subs(gdash, X0);
                gdash_value = abs(gdash_value);
                ea = 999999;
                g0 = X0;
                g1 = 0;
                gdash_value = 0;
                if (gdash_value < 1)
                    iteration = 1;
                    while (iteration <= iterations && es < ea)
                        g1 = subs(g, g0);
                        ea = abs((g1-g0)/g1);
                        g0 = double(g1);
                        nr = {iteration double(g1) double(ea)*100};
                        app.UITable.Data = [app.UITable.Data; nr];
                        iteration = iteration + 1;
                        if (isnan(ea) || isinf(ea))
                            status = false;
                            root = NaN;
                            precision = NaN;
                            excecutionTime = NaN;
                            iteration = NaN;                            
                            app.StatusLabel.FontColor = [1.00,0.00,0.0];
                            app.StatusLabel.Text = 'ERROR Divergence: Root is impossible to locate';
                            return
                        end
                    end
                    root = g1;
                    precision = floor(2 - log10(2 * ea));
                    excecutionTime = cputime - tstart;
                    status = true;
                else
                    app.StatusLabel.FontColor = [1.00,0.00,0.0];
                    app.StatusLabel.Text = 'ERROR Divergence: Root is impossible to locate';
                    root = NaN;
                    precision = NaN;
                    excecutionTime = NaN;
                    iteration = NaN;
                    status = false;
                    return
                end
        end
        
        function [root,precision,excecutionTime,iteration, status] = NewtonRaphson(app, equstr, X0, es, iterations)
            app.StatusLabel.FontColor = [0.00,0.00,0.00];
            app.UITable.ColumnName = {'iteration' 'Xr' 'es (%)'};
            tstart = cputime;
            g = str2sym(equstr);
            gdash = diff(g);
            gdash_value1 = subs(gdash, X0);
            gdash_value2 = abs(gdash_value1);
            ea = 999999;
            g0 = X0;
            g1 = 0;
            gdash_value2 = 0;
            if (gdash_value2 < 1)
                iteration = 1;
                while (iteration <= iterations && es < ea)
                    gx = subs(g, g0);
                    g2= subs (gdash,g0);
                    g1=g0-(gx/g2);
                    ea = abs((g1-g0)/g1);
                    g0 = double(g1);
                    nr = {iteration double(g1) double(ea)*100};
                    app.UITable.Data = [app.UITable.Data; nr];
                    iteration = iteration + 1;
                    if (isnan(ea) || isinf(ea))
                       status = false;
                       root = NaN;
                       precision = NaN;
                       excecutionTime = NaN;
                       iteration = NaN;                            
                       app.StatusLabel.FontColor = [1.00,0.00,0.0];
                       app.StatusLabel.Text = 'ERROR Divergence: Root is impossible to locate';
                       return
                    end                    
                end
                log
                root = g1;
                precision = floor(2 - log10(2 * ea));
                excecutionTime = cputime - tstart;
                status = true;
            else
                app.StatusLabel.FontColor = [1.00,0.00,0.0];
                app.StatusLabel.Text = 'ERROR Divergence: Root is impossible to locate';
                root = NaN;
                precision = NaN;
                excecutionTime = NaN;
                iteration = NaN;
                status = false;
                return
            end
        end
        
        function [root,precision,excecutionTime,iteration, status] = falsePosition(app, equstr, xl, xu, es, iterations)
            app.StatusLabel.FontColor = [0.00,0.00,0.00];
            app.UITable.ColumnName = {'iteration' 'xl' 'fxl' 'xu' 'fxu' 'xr' 'f(xr)' 'es (%)'};
            tstart = cputime;
            equ = str2sym(equstr);
            fxl = subs(equ,xl);
            fxu = subs(equ,xu);
            xrOld = 0;
            if (fxl * fxu < 0)
                iteration = 1;
                error = 99999999;
                while (iteration <= iterations && es < error)
                    fxl=subs(equ,xl);
                    fxu=subs(equ,xu);
                    xrnew=((xu*fxl)-(xl*fxu))/(fxl-fxu);
                    error = abs((xrnew - xrOld)/xrnew);
                    fxr = subs(equ,xrnew);
                    nr = {iteration xl double(fxl) xu double(fxu) double(xrnew) double(fxr) double(error)*100};
                    app.UITable.Data = [app.UITable.Data; nr];
                    if (fxr < 0)
                        xl = double(xrnew);
                    else
                        xu = double(xrnew);
                    end
                    xrOld = xrnew;
                    iteration = iteration + 1;
                end
                root = xrnew;
                precision = floor(2 - log10(2 * error));
                excecutionTime = cputime - tstart;
                status = true;
            else
                app.Statuslabel.fontColor = [1.00,0.00,0.0];
                app.Statuslabel.Text = 'ERROR Bad Interval: Root is impossible to locate';
                root = NaN;
                precision = NaN;
                excecutionTime = NaN;
                iteration = NaN;
                status = false;
                return
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            [filename,filepath] = uigetfile({'.txt'},'Search For the text file containing equations');
            fullname = [filepath filename];
            if ischar(fullname)
                if  isfile(fullname)
                    app.FiledirectoryEditField.Value = fullname;
                    fileID = fopen(fullname,'r');
                    data = textscan(fileID,'%s');
                    fclose(fileID);
                    stringData = string(data{:});
                    app.Equations = transpose(stringData);
                    t = "";
                    count = 0;
                    for i= app.Equations
                         if count ~= 0
                            t = strcat(t, ",");
                        end
                         t = strcat(t, i);
                         count = count + 1;
                    end
                    app.EquationEditField.Value = t;
                    app.Equations = FormulateProcess(app);
                    app.NoOFEquations = length(app.Equations);
                    app.CurrentEquationEditField.Value = app.Equations{app.CurrentEquation};                    
                 else
                    app.StatusLabel.Text ="ERROR :file does not exist!!";
                end
            end

        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
                    app.Equations = FormulateProcess(app);
                    app.NoOFEquations = length(app.Equations);
                    app.CurrentEquationEditField.Value = app.Equations{app.CurrentEquation};              
            if isempty(app.Equations)
                app.StatusLabel.FontColor = [1.00,0.00,0.0];
                app.StatusLabel.Text = "ERROR : Enter Equation or browse for File";
            else
                app.StatusLabel.FontColor = [0.00,0.00,0.00];
                app.StatusLabel.Text = 'Status : Equations Inserted';
                app.CurrentEquationEditField.Value = app.Equations{app.CurrentEquation};
                value = app.MethodSelectionDropDown.Value;
                if (strcmp(value,"Bisection"))
                    XL = app.XLEditField.Value;
                    XU = app.XUEditField.Value;
                    equ = app.Equations{app.CurrentEquation};
                    es = app.EpsilonEditField.Value;
                    iter = app.MaxiterationsEditField.Value;
                    app.UITable.Data = {};
                    [root,precision,excecutionTime,iteration, status] = Bisection(app,equ,XL,XU,es,iter);
                    if status == true
                    app.ApproximaterootEditField.Value = root;
                    app.PrecsionEditField.Value = precision;
                    app.ExcecutionTimesEditField.Value = excecutionTime;
                    app.IterationstookEditField.Value = iteration-1;  
                    end
                elseif (strcmp(value,"Secant"))
                    XL = app.XLEditField.Value;
                    XU = app.XUEditField.Value;
                    equ = app.Equations{app.CurrentEquation};
                    es = app.EpsilonEditField.Value;
                    iter = app.MaxiterationsEditField.Value;
                    app.UITable.Data = {};
                    [root,precision,excecutionTime,iteration, status] = Secant(app,equ,XL,XU,es,iter);
                    if status == true
                    app.ApproximaterootEditField.Value = double(root);
                    app.PrecsionEditField.Value = double(precision);
                    app.ExcecutionTimesEditField.Value = excecutionTime;
                    app.IterationstookEditField.Value = iteration-1;  
                    end
                elseif (strcmp(value,"Fixed Point"))
                    X0 = app.IntialGuessEditField.Value;
                    equ = app.Equations{app.CurrentEquation};
                    es = app.EpsilonEditField.Value;
                    iter = app.MaxiterationsEditField.Value;
                    app.UITable.Data = {};
                    [root,precision,excecutionTime,iteration, status] = FixedPoint(app,equ,X0,es,iter);
                    if status == true
                    app.ApproximaterootEditField.Value = double(root);
                    app.PrecsionEditField.Value = double(precision);
                    app.ExcecutionTimesEditField.Value = excecutionTime;
                    app.IterationstookEditField.Value = iteration-1;
                    end
                elseif (strcmp(value,"Newton-Raphson"))
                    X0 = app.IntialGuessEditField.Value;
                    equ = app.Equations{app.CurrentEquation};
                    es = app.EpsilonEditField.Value;
                    iter = app.MaxiterationsEditField.Value;
                    app.UITable.Data = {};
                    [root,precision,excecutionTime,iteration, status] = NewtonRaphson(app,equ,X0,es,iter);
                    if status == true
                    app.ApproximaterootEditField.Value = double(root);
                    app.PrecsionEditField.Value = double(precision);
                    app.ExcecutionTimesEditField.Value = excecutionTime;
                    app.IterationstookEditField.Value = iteration-1;
                    end
                elseif (strcmp(value,"False Postion"))
                    XL = app.XLEditField.Value;
                    XU = app.XUEditField.Value;
                    equ = app.Equations{app.CurrentEquation};
                    es = app.EpsilonEditField.Value;
                    iter = app.MaxiterationsEditField.Value;
                    app.UITable.Data = {};
                    [root,precision,excecutionTime,iteration, status] = falsePosition(app,equ,XL,XU,es,iter);
                    if status == true
                    app.ApproximaterootEditField.Value = double(root);
                    app.PrecsionEditField.Value = double(precision);
                    app.ExcecutionTimesEditField.Value = excecutionTime;
                    app.IterationstookEditField.Value = iteration-1;  
                    end                    
                else
                    return
                end
            end
        end

        % Value changed function: MethodSelectionDropDown
        function MethodSelectionDropDownValueChanged(app, event)
            value = app.MethodSelectionDropDown.Value;
            if (strcmp(value,"Bisection"))
                app.MethodSelectedPanel.Title = 'Bisection Method';
                app.XLEditField.Visible = true;
                app.XLEditFieldLabel.Visible = true;
                app.XLEditFieldLabel.Text = 'XL';
                app.XUEditField.Visible = true;
                app.XUEditFieldLabel.Visible = true;   
                app.XUEditFieldLabel.Text = 'XU';
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            elseif (strcmp(value,"False Postion"))
                app.MethodSelectedPanel.Title = 'False Postion Method';
                app.XLEditField.Visible = true;
                app.XLEditFieldLabel.Visible = true;
                app.XLEditFieldLabel.Text = 'XL';
                app.XUEditField.Visible = true;
                app.XUEditFieldLabel.Visible = true;
                 app.XUEditFieldLabel.Text = 'XU';
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            elseif (strcmp(value,"Fixed Point"))
                app.MethodSelectedPanel.Title = 'Fixed Point Method';
                app.IntialGuessEditField.Visible = true;
                app.IntialGuessEditFieldLabel.Visible = true;
                app.XLEditFieldLabel.Visible = false;
                app.XLEditField.Visible = false;
                app.XUEditFieldLabel.Visible = false;
                app.XUEditField.Visible = false;
            elseif (strcmp(value,"Newton-Raphson"))
                app.MethodSelectedPanel.Title = 'Newton-Raphson Method';
                app.IntialGuessEditField.Visible = true;
                app.IntialGuessEditFieldLabel.Visible = true;
                app.XLEditFieldLabel.Visible = false;
                app.XLEditField.Visible = false;
                app.XUEditFieldLabel.Visible = false;
                app.XUEditField.Visible = false;
            elseif (strcmp(value,"Secant"))
                app.MethodSelectedPanel.Title = 'Secant Method';
                app.XLEditFieldLabel.Text = 'X(i-1)';
                app.XLEditField.Visible = true;
                app.XLEditFieldLabel.Visible = true;
                app.XUEditFieldLabel.Text = 'X(i)';
                app.XUEditField.Visible = true;
                app.XUEditFieldLabel.Visible = true;
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            else
                app.MethodSelectedPanel.Title = 'Method Selected (None)';
                app.XLEditFieldLabel.Visible = false;
                app.XLEditField.Visible = false;
                app.XUEditFieldLabel.Visible = false;
                app.XUEditField.Visible = false;
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.StatusLabel.Text = "Status : Not Running";
            app.UITable.Data = {};
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
           if (app.CurrentEquation < app.NoOFEquations)
               app.CurrentEquation = app.CurrentEquation + 1;
               app.CurrentEquationEditField.Value = app.Equations{app.CurrentEquation};
           end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
              if (app.CurrentEquation > 1)
               app.CurrentEquation = app.CurrentEquation - 1;
               app.CurrentEquationEditField.Value = app.Equations{app.CurrentEquation};
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolvingEquationusingNumericalMethodsLabel = uilabel(app.UIFigure);
            app.SolvingEquationusingNumericalMethodsLabel.Text = 'Solving Equation using Numerical Methods';
            app.BrowseFileButton = uibutton(app.UIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Enter the required equation separated by comma of browse OR select file contating the equation !!';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.OutputScreenLabel = uilabel(app.UIFigure);
            app.OutputScreenLabel.Text = 'Output Screen';
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.MaxiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxiterationsEditFieldLabel.Text = 'Max iterations';
            app.MaxiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxiterationsEditField.Value = 50;
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.Value = 1e-05;
            app.FiledirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.FiledirectoryEditFieldLabel.Text = 'File directory';
            app.FiledirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.MethodSelectionDropDownLabel = uilabel(app.UIFigure);
            app.MethodSelectionDropDownLabel.Text = 'Method Selection';
            app.MethodSelectionDropDown = uidropdown(app.UIFigure);
            app.MethodSelectionDropDown.Items = {'None', 'Bisection', 'False Postion', 'Fixed Point', 'Newton-Raphson', 'Secant'};
            app.MethodSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodSelectionDropDownValueChanged, true);
            app.MethodSelectionDropDown.Value = 'None';
            app.MethodSelectedPanel = uipanel(app.UIFigure);
            app.MethodSelectedPanel.Title = 'Method Selected';
            app.XLEditFieldLabel = uilabel(app.MethodSelectedPanel);
            app.XLEditFieldLabel.Visible = 'off';
            app.XLEditFieldLabel.Text = 'XL';
            app.XLEditField = uieditfield(app.MethodSelectedPanel, 'numeric');
            app.XLEditField.Visible = 'off';
            app.XUEditFieldLabel = uilabel(app.MethodSelectedPanel);
            app.XUEditFieldLabel.Visible = 'off';
            app.XUEditFieldLabel.Text = 'XU';
            app.XUEditField = uieditfield(app.MethodSelectedPanel, 'numeric');
            app.XUEditField.Visible = 'off';
            app.IntialGuessEditFieldLabel = uilabel(app.MethodSelectedPanel);
            app.IntialGuessEditFieldLabel.Visible = 'off';
            app.IntialGuessEditFieldLabel.Text = 'Intial Guess';
            app.IntialGuessEditField = uieditfield(app.MethodSelectedPanel, 'text');
            app.IntialGuessEditField.Visible = 'off';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status : Not Runing';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CurrentEquationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentEquationEditFieldLabel.Text = 'Current Equation';
            app.CurrentEquationEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentEquationEditField.Editable = 'off';
            app.IterationstookEditFieldLabel = uilabel(app.UIFigure);
            app.IterationstookEditFieldLabel.Text = 'Iterations took';
            app.IterationstookEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationstookEditField.Editable = 'off';
            app.ApproximaterootEditFieldLabel = uilabel(app.UIFigure);
            app.ApproximaterootEditFieldLabel.Text = 'Approximate root';
            app.ApproximaterootEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApproximaterootEditField.Editable = 'off';
            app.PrecsionEditFieldLabel = uilabel(app.UIFigure);
            app.PrecsionEditFieldLabel.Text = 'Precsion';
            app.PrecsionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecsionEditField.Editable = 'off';
            app.ExcecutionTimesEditFieldLabel = uilabel(app.UIFigure);
            app.ExcecutionTimesEditFieldLabel.Text = 'Excecution Time (s)';
            app.ExcecutionTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExcecutionTimesEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end